classdef Slots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlotsUIFigure  matlab.ui.Figure
        dSliderLabel   matlab.ui.control.Label
        dSlider        matlab.ui.control.Slider
        sSliderLabel   matlab.ui.control.Label
        sSlider        matlab.ui.control.Slider
        RoSliderLabel  matlab.ui.control.Label
        RoSlider       matlab.ui.control.Slider
        RnSliderLabel  matlab.ui.control.Label
        RnSlider       matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes3        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: dSlider
        function dSliderValueChanged(app, event)
            mean_1 = 0;
            mean_2 = app.dSlider.Value;
            SD_1=1;
            SD_2=1;
            x=-3:0.01:6;
            y_1=normpdf(x,mean_1,SD_1);
            y_2=normpdf(x,mean_2,SD_2);
            c0 = app.sSlider.Value;
            Po = app.RoSlider.Value;
            Pn = app.RnSlider.Value;
            %---%
            plot(app.UIAxes, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes)
            plot(app.UIAxes, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes, [c0 c0], [0 0.5], '-', 'Color',[0 0 0])
            hold(app.UIAxes)
            xlim(app.UIAxes, [-3, 6])
            % Plot ROC
            param_all = [mean_2, Po, Pn];
            [HR, FAR, zHR, zFAR]=getROC('Slots',param_all);
            [HR_HT, FAR_HT, zHR_HT, zFAR_HT]=getROC('2HT',param_all(2:3));
            [HR_SD, FAR_SD, zHR_SD, zFAR_SD]=getROC('EVSD',param_all(1));
            [FAR0, zFAR0]=c2FAR('Slots',c0,param_all);
            [HR0, zHR0]=c2HR('Slots',c0,param_all);
            plot(app.UIAxes2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2)
            plot(app.UIAxes2, FAR_HT, HR_HT,':','Color',[1 0 0])
            plot(app.UIAxes2, FAR_SD, HR_SD,':','Color',[0 0 1])
            plot(app.UIAxes2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2)
            % Plot zROC
            plot(app.UIAxes3, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3)
            plot(app.UIAxes3, zFAR_HT, zHR_HT,':','Color',[1 0 0])
            plot(app.UIAxes3, zFAR_SD, zHR_SD,':','Color',[0 0 1])
            plot(app.UIAxes3, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3)
            xlim(app.UIAxes3, [-3, 3])
            ylim(app.UIAxes3, [-3, 3])
        end

        % Value changed function: sSlider
        function sSliderValueChanged(app, event)
            mean_1 = 0;
            mean_2 = app.dSlider.Value;
            SD_1=1;
            SD_2=1;
            x=-3:0.01:6;
            y_1=normpdf(x,mean_1,SD_1);
            y_2=normpdf(x,mean_2,SD_2);
            c0 = app.sSlider.Value;
            Po = app.RoSlider.Value;
            Pn = app.RnSlider.Value;
            %---%
            plot(app.UIAxes, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes)
            plot(app.UIAxes, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes, [c0 c0], [0 0.5], '-', 'Color',[0 0 0])
            hold(app.UIAxes)
            xlim(app.UIAxes, [-3, 6])
            % Plot ROC
            param_all = [mean_2, Po, Pn];
            [HR, FAR, zHR, zFAR]=getROC('Slots',param_all);
            [HR_HT, FAR_HT, zHR_HT, zFAR_HT]=getROC('2HT',param_all(2:3));
            [HR_SD, FAR_SD, zHR_SD, zFAR_SD]=getROC('EVSD',param_all(1));
            [FAR0, zFAR0]=c2FAR('Slots',c0,param_all);
            [HR0, zHR0]=c2HR('Slots',c0,param_all);
            plot(app.UIAxes2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2)
            plot(app.UIAxes2, FAR_HT, HR_HT,':','Color',[1 0 0])
            plot(app.UIAxes2, FAR_SD, HR_SD,':','Color',[0 0 1])
            plot(app.UIAxes2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2)
            % Plot zROC
            plot(app.UIAxes3, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3)
            plot(app.UIAxes3, zFAR_HT, zHR_HT,':','Color',[1 0 0])
            plot(app.UIAxes3, zFAR_SD, zHR_SD,':','Color',[0 0 1])
            plot(app.UIAxes3, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3)
            xlim(app.UIAxes3, [-3, 3])
            ylim(app.UIAxes3, [-3, 3])
        end

        % Value changed function: RoSlider
        function RoSliderValueChanged(app, event)
            mean_1 = 0;
            mean_2 = app.dSlider.Value;
            SD_1=1;
            SD_2=1;
            x=-3:0.01:6;
            y_1=normpdf(x,mean_1,SD_1);
            y_2=normpdf(x,mean_2,SD_2);
            c0 = app.sSlider.Value;
            Po = app.RoSlider.Value;
            Pn = app.RnSlider.Value;
            %---%
            plot(app.UIAxes, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes)
            plot(app.UIAxes, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes, [c0 c0], [0 0.5], '-', 'Color',[0 0 0])
            hold(app.UIAxes)
            xlim(app.UIAxes, [-3, 6])
            % Plot ROC
            param_all = [mean_2, Po, Pn];
            [HR, FAR, zHR, zFAR]=getROC('Slots',param_all);
            [HR_HT, FAR_HT, zHR_HT, zFAR_HT]=getROC('2HT',param_all(2:3));
            [HR_SD, FAR_SD, zHR_SD, zFAR_SD]=getROC('EVSD',param_all(1));
            [FAR0, zFAR0]=c2FAR('Slots',c0,param_all);
            [HR0, zHR0]=c2HR('Slots',c0,param_all);
            plot(app.UIAxes2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2)
            plot(app.UIAxes2, FAR_HT, HR_HT,':','Color',[1 0 0])
            plot(app.UIAxes2, FAR_SD, HR_SD,':','Color',[0 0 1])
            plot(app.UIAxes2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2)
            % Plot zROC
            plot(app.UIAxes3, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3)
            plot(app.UIAxes3, zFAR_HT, zHR_HT,':','Color',[1 0 0])
            plot(app.UIAxes3, zFAR_SD, zHR_SD,':','Color',[0 0 1])
            plot(app.UIAxes3, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3)
            xlim(app.UIAxes3, [-3, 3])
            ylim(app.UIAxes3, [-3, 3])
            
        end

        % Value changed function: RnSlider
        function RnSliderValueChanged(app, event)
            mean_1 = 0;
            mean_2 = app.dSlider.Value;
            SD_1=1;
            SD_2=1;
            x=-3:0.01:6;
            y_1=normpdf(x,mean_1,SD_1);
            y_2=normpdf(x,mean_2,SD_2);
            c0 = app.sSlider.Value;
            Po = app.RoSlider.Value;
            Pn = app.RnSlider.Value;
            %---%
            plot(app.UIAxes, x, y_1, '-', 'Color', [0 0 0])
            hold(app.UIAxes)
            plot(app.UIAxes, x, y_2, '--', 'Color', [0 0 0])
            f1=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_2(x>c0))],...
                [0.7, 0.7, 0.7]);
            f2=fill(app.UIAxes, [x(x>c0), fliplr(x(x>c0))],...
                [zeros(size(x(x>c0))), fliplr(y_1(x>c0))],...
                [0.5, 0.5, 0.5]);
            set(f1,'facealpha',0.75, 'edgealpha', 0)
            set(f2,'facealpha',0.75, 'edgealpha', 0)
            plot(app.UIAxes, [c0 c0], [0 0.5], '-', 'Color',[0 0 0])
            hold(app.UIAxes)
            xlim(app.UIAxes, [-3, 6])
            % Plot ROC
            param_all = [mean_2, Po, Pn];
            [HR, FAR, zHR, zFAR]=getROC('Slots',param_all);
            [HR_HT, FAR_HT, zHR_HT, zFAR_HT]=getROC('2HT',param_all(2:3));
            [HR_SD, FAR_SD, zHR_SD, zFAR_SD]=getROC('EVSD',param_all(1));
            [FAR0, zFAR0]=c2FAR('Slots',c0,param_all);
            [HR0, zHR0]=c2HR('Slots',c0,param_all);
            plot(app.UIAxes2, [0,1], [0,1], '--', 'Color', [0 0 0])
            hold(app.UIAxes2)
            plot(app.UIAxes2, FAR_HT, HR_HT,':','Color',[1 0 0])
            plot(app.UIAxes2, FAR_SD, HR_SD,':','Color',[0 0 1])
            plot(app.UIAxes2, FAR, HR,'-','Color',[0 0 0])
            plot(app.UIAxes2, FAR0, HR0, 'o', 'Color', [0,0,0])
            hold(app.UIAxes2)
            % Plot zROC
            plot(app.UIAxes3, [-3,3], [-3,3], '--', 'Color', [0 0 0])
            hold(app.UIAxes3)
            plot(app.UIAxes3, zFAR_HT, zHR_HT,':','Color',[1 0 0])
            plot(app.UIAxes3, zFAR_SD, zHR_SD,':','Color',[0 0 1])
            plot(app.UIAxes3, zFAR, zHR,'-','Color',[0 0 0])
            plot(app.UIAxes3, zFAR0, zHR0,'o','Color',[0 0 0])
            hold(app.UIAxes3)
            xlim(app.UIAxes3, [-3, 3])
            ylim(app.UIAxes3, [-3, 3])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlotsUIFigure = uifigure('Visible', 'off');
            app.SlotsUIFigure.Name = 'Slots';
            app.dSliderLabel = uilabel(app.SlotsUIFigure);
            app.dSliderLabel.Text = 'd''';
            app.dSlider = uislider(app.SlotsUIFigure);
            app.dSlider.Limits = [-1 3];
            app.dSlider.MajorTicks = [-1 0 1 2 3];
            app.dSlider.MajorTickLabels = {'-1', '0', '1', '2', '3'};
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @dSliderValueChanged, true);
            app.dSlider.MinorTicks = [];
            app.dSlider.Value = 1;
            app.sSliderLabel = uilabel(app.SlotsUIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.SlotsUIFigure);
            app.sSlider.Limits = [-2 3];
            app.sSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.sSlider.MajorTickLabels = {'-3', '-2', '-1', '0', '1', '2', '3'};
            app.sSlider.ValueChangedFcn = createCallbackFcn(app, @sSliderValueChanged, true);
            app.sSlider.MinorTicks = [];
            app.RoSliderLabel = uilabel(app.SlotsUIFigure);
            app.RoSliderLabel.Text = 'Ro';
            app.RoSlider = uislider(app.SlotsUIFigure);
            app.RoSlider.Limits = [0 1];
            app.RoSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.RoSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.RoSlider.ValueChangedFcn = createCallbackFcn(app, @RoSliderValueChanged, true);
            app.RoSlider.MinorTicks = [];
            app.RoSlider.Value = 0.75;
            app.RnSliderLabel = uilabel(app.SlotsUIFigure);
            app.RnSliderLabel.Text = 'Rn';
            app.RnSlider = uislider(app.SlotsUIFigure);
            app.RnSlider.Limits = [0 1];
            app.RnSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.RnSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.RnSlider.ValueChangedFcn = createCallbackFcn(app, @RnSliderValueChanged, true);
            app.RnSlider.MinorTicks = [];
            app.UIAxes = uiaxes(app.SlotsUIFigure);
            title(app.UIAxes, 'The Signal Detection Component')
            xlabel(app.UIAxes, 'Strength of Evidence')
            ylabel(app.UIAxes, 'Density')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 6];
            app.UIAxes2 = uiaxes(app.SlotsUIFigure);
            title(app.UIAxes2, 'ROC')
            xlabel(app.UIAxes2, 'FA Rate')
            ylabel(app.UIAxes2, 'Hit Rate')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.SlotsUIFigure);
            title(app.UIAxes3, 'zROC')
            xlabel(app.UIAxes3, 'z(FA Rate)')
            ylabel(app.UIAxes3, 'z(HIt Rate)')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.SlotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Slots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlotsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlotsUIFigure)
        end
    end
end